classdef Group11 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        CoverPageTab                  matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        TerminateButton_2             matlab.ui.control.Button
        HTML                          matlab.ui.control.HTML
        GetStartedButton              matlab.ui.control.Button
        ColorCrafterTab               matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        NextPage                      matlab.ui.control.Button
        PreviousPage                  matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        ColorCrafterPanel             matlab.ui.container.Panel
        MenuPanel                     matlab.ui.container.Panel
        TerminateButton               matlab.ui.control.Button
        KValueEditField               matlab.ui.control.NumericEditField
        KValueEditFieldLabel          matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        LoadImageButton               matlab.ui.control.Button
        SaveImageButton               matlab.ui.control.Button
        axes1                         matlab.ui.control.UIAxes
        axes2                         matlab.ui.control.UIAxes
        EyeSphereTab                  matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        InitialPage                   matlab.ui.control.Button
        PrevPage                      matlab.ui.control.Button
        Image2                        matlab.ui.control.Image
        EyeSpherePanel                matlab.ui.container.Panel
        Parameters                    matlab.ui.container.Panel
        ParametersPanel               matlab.ui.container.Panel
        IntervalEditField             matlab.ui.control.NumericEditField
        IntervalEditFieldLabel        matlab.ui.control.Label
        ThresholdRatioEditField       matlab.ui.control.NumericEditField
        ThresholdRatioEditFieldLabel  matlab.ui.control.Label
        StandardDeviationEditField    matlab.ui.control.NumericEditField
        StandardDeviationEditFieldLabel  matlab.ui.control.Label
        OperationsPanel               matlab.ui.container.Panel
        TerminateButton2              matlab.ui.control.Button
        SaveEyeImageButton            matlab.ui.control.Button
        ProcessButton                 matlab.ui.control.Button
        LoadEyeImageButton            matlab.ui.control.Button
        axes3                         matlab.ui.control.UIAxes
        axes4                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        inputImage        % Input image for ColorCrafterTab
        outputImage       % Output image for ColorCrafterTab
        inputEyeImage     % Input image for EyeSphereTab
        UnwrappedImage    % Output image for EyeSphereTab for only unwrapped image
    end
    
    methods (Access = private)
        
        function InitializeTabs(app)
            app.ColorCrafterTab.Visible = 'off';
            app.EyeSphereTab.Visible = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Ask the user to pick an image file.
            [file, path] = uigetfile({'*.jpg;*.png;*.ppm', 'Image Files'}); 
            
            % User cancels
            if isequal(file, 0)
                return; 
            else
                % Build the full path to the file.
                filepath = fullfile(path, file);
                
                % Read the file and set it as the input image.
                app.inputImage = imread(filepath);
            
                % Not an RGB image
                if size(app.inputImage, 3) ~= 3
                    uialert(app.UIFigure, 'Please load a color image (RGB).', 'Invalid Image');
                    return; 
                end
            
                % Display the image where it belongs
                imshow(app.inputImage, [], 'Parent', app.axes1);
            
                % clean it up
                if isvalid(app.axes2) && ~isempty(get(app.axes2, 'Children'))
                    cla(app.axes2); % Clear 
                    app.axes2.Visible = 'off'; % hide it.
                end
            
                % Just to be sure clear the axis.
                cla(app.axes2);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Get the value of K
            K = app.KValueEditField.Value;
            
            % K must be numeric, an integer, and between 2 and 11
            if ~isnumeric(K) || K < 2 || K > 11 || K ~= floor(K)
      
                uialert(app.UIFigure, 'K must be an integer between 2 and 11.', 'Invalid K Value');
                return; 
            end
            
            % No image loaded
            if isempty(app.inputImage) || ndims(app.inputImage) < 2

                uialert(app.UIFigure, 'Please load a valid image first.', 'No Valid Image Loaded');
                return;
            end
            
            % Scale the image to [0,1]
            scaledImage = double(app.inputImage) / 255;
            % Get the dimensions
            [rows, cols, channels] = size(scaledImage);
            reshaped = reshape(scaledImage, rows * cols, channels);
            
            % maximum number of iterations for K-means.
            maxIterations = 100;
            
            % clustering
            try
                % Perform K-means 
                [idx, centroids] = kmeans_custom(reshaped, K, maxIterations, 'sqEuclidean');
            catch ME
               
                uialert(app.UIFigure, ['K-means clustering failed: ' ME.message], 'Clustering Error');
                return; 
            end
            
            % Rebuild the clustered image with the centroids and indices
            clustered = reshape(centroids(idx, :), rows, cols, channels);
            
            % Bring it back to [0,255] for display
            app.outputImage = uint8(clustered * 255);
            
            % Show the clustered image 
            imshow(app.outputImage, 'Parent', app.axes2);
           
            axis(app.axes2, 'image'); 
            app.axes2.Visible = 'on';
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            % Check if there is an output image to save
            if isempty(app.outputImage)
               
               uialert(app.UIFigure, 'No output image to save. Please run the clustering first.', 'Save Image');
                return; % Exit the function
            end
            [file, path] = uiputfile('*.ppm', 'Save Image As');
            
            if isequal(file,0)
                return; 
            else
                filepath = fullfile(path, file);
                try
                    % Try saving the output image in PPM format
                    imwrite(app.outputImage, filepath, 'ppm');

                    uialert(app.UIFigure, ' Image saved successfully.', 'Save Successful');
                catch ME
                    uialert(app.UIFigure, ['Failed to save the image: ' ME.message], 'Save Error');
                end
            end
        end

        % Button pushed function: GetStartedButton
        function GetStartedButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ColorCrafterTab;    
        end

        % Button pushed function: TerminateButton, TerminateButton2, 
        % ...and 1 other component
        function TerminateButtonPushed(app, event)
            % Ask for confirmation before closing
            choice = questdlg('Are you sure you want to terminate the application?', ...
                              'Exit Confirmation', 'Yes', 'No', 'No');
                          
            % Close the GUI  'Yes'
            if strcmp(choice, 'Yes')
                close(app.UIFigure);
            end
        end

        % Button pushed function: LoadEyeImageButton
        function LoadEyeImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.ppm', 'Image Files'});
            
            if isequal(file, 0)
                return; 
            else

                filepath = fullfile(path, file);
            
                app.inputEyeImage = imread(filepath);

           
                if size(app.inputEyeImage, 3) ~= 3
   
                    uialert(app.UIFigure, 'Please load a color image (RGB).', 'Invalid Image');
                    return; 
                end

                imshow(app.inputEyeImage, [], 'Parent', app.axes3);
                
                if isvalid(app.axes4) && ~isempty(get(app.axes4, 'Children'))
                    cla(app.axes4);  
                    app.axes4.Visible = 'off';  
                end


                cla(app.axes4);
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            % Retrieve user input values for standard deviation, threshold ratio, and interval
            stdDeviation = app.StandardDeviationEditField.Value;
            threshold = app.ThresholdRatioEditField.Value;
            interval = app.IntervalEditField.Value;
            
            % Validate user inputs
            if stdDeviation <= 0 || threshold <= 0 || interval <= 0
                uialert(app.UIFigure, 'Input values must be positive.', 'Invalid Input');
                return;  
            end
            
            % Check image loaded
            if isempty(app.inputEyeImage)
                uialert(app.UIFigure, 'Please load an image before processing.', 'No Image Loaded');
                return; 
            end
            
            % RGB to HSI
            [H, S, I] = rgb_hsi(app.inputEyeImage);
            
            % Display the original image and HSI channels
            figure;
            subplot(2, 4, 1), imshow(app.inputEyeImage), title('Original RGB Image');
            subplot(2, 4, 2), imshow(I), title('Intensity Channel');
            
            % Canny edge detector on the intensity channel
            edgeMap = canny(I, threshold, stdDeviation);
            subplot(2, 4, 3), imshow(edgeMap), title('Edge Map');
            
            % Pupil and iris boundaries using a circular Hough transform
            pupilRadiusRange = [10, 20]; % range for the pupil
            irisRadiusRange = [40, 60]; %  range for the iris
            stepSize = 1; % Radius step size value
            
            [center, pupilRadius, irisRadius] = chough(edgeMap, pupilRadiusRange, irisRadiusRange, stepSize);
            
            % Display iris and pupil boundaries
            disp(['Pupil Radius: ', num2str(pupilRadius)]);
            disp(['Iris Radius: ', num2str(irisRadius)]);
            
            % Display the detected boundaries over the intensity channel image
            subplot(2, 4, 5), imshow(I), title('Detected Circles');
            hold on;
            viscircles(center, pupilRadius, 'Color', 'r'); % Red circle for pupil
            viscircles(center, irisRadius, 'Color', 'b');  % Blue circle for iris
            hold off;
            
            % Unwrap the Iris Using the Daugman Rubber Sheet Model
            try
                % boundaries for the iris and pupil
                irisBoundary = [center(1), center(2), irisRadius];
                pupilBoundary = [center(1), center(2), pupilRadius];
                % scaling factors for angular and radial resolution
                angularScale = 3;  
                radialscale = 2;

                app.outputImage = daugman(app.inputEyeImage, irisBoundary, pupilBoundary, interval, angularScale, radialscale);
            
                % unwrapped image
                subplot(2, 4, 6), imshow(uint8(app.outputImage)), title('Unwrapped Iris Image');
                imshow(app.outputImage, 'Parent', app.axes4);
            catch ME
             
                uialert(app.UIFigure, ['Error in Daugman process: ', ME.message], 'Processing Error');
                return;
            end
        end

        % Button pushed function: PreviousPage
        function PreviousPageButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.CoverPageTab;  
        end

        % Button pushed function: NextPage
        function NextPageButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.EyeSphereTab;  
        end

        % Button pushed function: PrevPage
        function PrevPageButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ColorCrafterTab;  
        end

        % Button pushed function: InitialPage
        function InitialPageButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.CoverPageTab;  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CoverPageTab = uitab(app.TabGroup);
            app.CoverPageTab.Title = 'Cover Page';
            app.GridLayout = uigridlayout(app.CoverPageTab);
            app.GetStartedButton = uibutton(app.GridLayout, 'push');
            app.GetStartedButton.ButtonPushedFcn = createCallbackFcn(app, @GetStartedButtonPushed, true);
            app.GetStartedButton.Text = 'Get Started';
            app.HTML = uihtml(app.GridLayout);
            app.HTML.HTMLSource = '<!DOCTYPE html><html lang="en"><head>    <meta charset="UTF-8">    <meta name="viewport" content="width=device-width, initial-scale=1.0">    <title>IrisFusion - CMPE488 Project</title>    <link href="https://fonts.googleapis.com/css2?family=Playfair+Display:wght@700&family=Roboto:wght@400;500&display=swap" rel="stylesheet">    <style>        * {            margin: 0;            padding: 0;            box-sizing: border-box;        }        body {            font-family: ''Roboto'', sans-serif;            background-color: #F8EDE3;            display: flex;            justify-content: center;            align-items: center;            height: 100vh;            color: #3B3030;            padding: 20px;        }        .cover-container {            background: #3B3030;            border-radius: 20px;            box-shadow: 0 15px 25px rgba(0, 0, 0, 0.5);            width: 100%;            max-width: 700px;            padding: 30px 25px;            text-align: center;            animation: fadeIn 2s ease-in-out;            display: flex;            flex-direction: column;            align-items: center;        }        .header {            margin-bottom: 30px;        }        .header h1 {            font-family: ''Playfair Display'', serif;            font-size: 3.5em;            margin-bottom: 10px;            color: #D2B48C;            text-shadow: 2px 2px 5px rgba(0, 0, 0, 0.3);        }        .contributors {            margin-top: 25px;            animation: contributorsAnimation 20s infinite;        }        .contributors h3 {            font-family: ''Playfair Display'', serif;            font-size: 2.5em;            margin-bottom: 20px;            color: #D2B48C;            text-shadow: 1px 1px 3px rgba(0, 0, 0, 0.2);        }        .contributors-list {            display: flex;            justify-content: center;            flex-wrap: wrap;            gap: 20px;        }        .contributor {            background: #4B3838;            border-radius: 10px;            padding: 10px 15px;            width: 140px;            text-align: center;            transition: transform 0.3s, background 0.3s;            box-shadow: 0 5px 15px rgba(0, 0, 0, 0.3);        }        .contributor:hover {            transform: translateY(-5px);            background: #5C4949;        }        .contributor-name {            font-size: 1em;            color: #F5F5F5;            margin-top: 5px;        }        .cta-section {            margin-top: 30px;            padding: 15px;            background: #D2B48C;            border-radius: 15px;            box-shadow: 0 10px 20px rgba(0, 0, 0, 0.3);            width: 100%;            max-width: 600px;            text-align: center;            animation: fadeIn 2s ease-in-out;        }        .cta-section p {            font-size: 1.5em;            color: #3B3030;            margin-bottom: 0;            font-weight: 500;        }        .footer {            margin-top: 40px;            font-size: 0.95em;            color: #D2B48C;            text-shadow: 1px 1px 2px rgba(0, 0, 0, 0.2);        }        .group-section {            margin-top: 40px;            color: #D2B48C;            font-family: ''Playfair Display'', serif;            text-align: center;        }        .group-section h3 {            font-size: 2em;            margin-bottom: 10px;        }        @keyframes fadeIn {            from { opacity: 0; transform: translateY(-20px); }            to { opacity: 1; transform: translateY(0); }        }        @keyframes contributorsAnimation {            0%, 100% { transform: scale(1); }            25% { transform: scale(1.05); }            50% { transform: scale(1); }            75% { transform: scale(1.05); }        }    </style></head><body>    <div class="cover-container">        <div class="header">            <h1>IrisFusion</h1>        </div>        <div class="contributors">            <h3>Contributors</h3>            <div class="contributors-list">                <div class="contributor">                    <div class="contributor-name">Edin Koç</div>                </div>                <div class="contributor">                    <div class="contributor-name">Zeynep Erkılıç</div>                </div>                <div class="contributor">                    <div class="contributor-name">Duygu Aydemir</div>                </div>                <div class="contributor">                    <div class="contributor-name">Sinem Aydın</div>                </div>            </div>        </div>        <div class="cta-section">            <p>Would you like to try a once in a lifetime experience?</p>        </div>        <div class="group-section">            <h3>Group 11</h3>        </div>        <div class="footer">            <p>© 2024 CMPE488 - All Rights Reserved.</p>        </div>    </div></body></html>';
            app.TerminateButton_2 = uibutton(app.GridLayout, 'push');
            app.TerminateButton_2.ButtonPushedFcn = createCallbackFcn(app, @TerminateButtonPushed, true);
            app.TerminateButton_2.Icon = fullfile(pathToMLAPP, 'icons', 'terminate.png');
            app.TerminateButton_2.Text = 'Terminate';
            app.ColorCrafterTab = uitab(app.TabGroup);
            app.ColorCrafterTab.Title = 'ColorCrafter';
            app.GridLayout3 = uigridlayout(app.ColorCrafterTab);
            app.axes2 = uiaxes(app.GridLayout3);
            title(app.axes2, 'Output Image')
            app.axes2.XTick = [];
            app.axes2.YTick = [];
            app.axes1 = uiaxes(app.GridLayout3);
            title(app.axes1, 'Input Image')
            app.axes1.XTick = [];
            app.axes1.YTick = [];
            app.MenuPanel = uipanel(app.GridLayout3);
            app.MenuPanel.Title = 'Menu';
            app.SaveImageButton = uibutton(app.MenuPanel, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Icon = fullfile(pathToMLAPP, 'icons', 'download.png');
            app.SaveImageButton.Text = 'Save Image';
            app.LoadImageButton = uibutton(app.MenuPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Icon = fullfile(pathToMLAPP, 'icons', 'upload.svg');
            app.LoadImageButton.Text = 'Load Image';
            app.RunButton = uibutton(app.MenuPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = fullfile(pathToMLAPP, 'icons', 'loading.png');
            app.RunButton.Text = 'Run';
            app.KValueEditFieldLabel = uilabel(app.MenuPanel);
            app.KValueEditFieldLabel.Text = 'K Value';
            app.KValueEditField = uieditfield(app.MenuPanel, 'numeric');
            app.KValueEditField.Placeholder = 'Enter K Value';
            app.TerminateButton = uibutton(app.MenuPanel, 'push');
            app.TerminateButton.ButtonPushedFcn = createCallbackFcn(app, @TerminateButtonPushed, true);
            app.TerminateButton.Icon = fullfile(pathToMLAPP, 'icons', 'terminate.png');
            app.TerminateButton.Text = 'Terminate';
            app.ColorCrafterPanel = uipanel(app.GridLayout3);
            app.ColorCrafterPanel.Title = 'ColorCrafter';
            app.Image = uiimage(app.GridLayout3);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logos', 'logo1.png');
            app.PreviousPage = uibutton(app.GridLayout3, 'push');
            app.PreviousPage.ButtonPushedFcn = createCallbackFcn(app, @PreviousPageButtonPushed, true);
            app.PreviousPage.Text = 'Previous Page';
            app.NextPage = uibutton(app.GridLayout3, 'push');
            app.NextPage.ButtonPushedFcn = createCallbackFcn(app, @NextPageButtonPushed, true);
            app.NextPage.Text = 'Next Page';
            app.EyeSphereTab = uitab(app.TabGroup);
            app.EyeSphereTab.Title = 'EyeSphere';
            app.GridLayout2 = uigridlayout(app.EyeSphereTab);
            app.axes4 = uiaxes(app.GridLayout2);
            title(app.axes4, 'Output Image')
            app.axes4.XTick = [];
            app.axes4.YTick = [];
            app.axes3 = uiaxes(app.GridLayout2);
            title(app.axes3, 'Input Image')
            app.axes3.XTick = [];
            app.axes3.YTick = [];
            app.Parameters = uipanel(app.GridLayout2);
            app.Parameters.Title = 'Menu';
            app.OperationsPanel = uipanel(app.Parameters);
            app.OperationsPanel.Title = 'Operations';
            app.LoadEyeImageButton = uibutton(app.OperationsPanel, 'push');
            app.LoadEyeImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEyeImageButtonPushed, true);
            app.LoadEyeImageButton.Icon = fullfile(pathToMLAPP, 'icons', 'upload.svg');
            app.LoadEyeImageButton.Text = 'Load Image';
            app.ProcessButton = uibutton(app.OperationsPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Icon = fullfile(pathToMLAPP, 'icons', 'loading.png');
            app.ProcessButton.Text = 'Process';
            app.SaveEyeImageButton = uibutton(app.OperationsPanel, 'push');
            app.SaveEyeImageButton.Icon = fullfile(pathToMLAPP, 'icons', 'download.png');
            app.SaveEyeImageButton.Text = 'Save Image';
            app.TerminateButton2 = uibutton(app.OperationsPanel, 'push');
            app.TerminateButton2.ButtonPushedFcn = createCallbackFcn(app, @TerminateButtonPushed, true);
            app.TerminateButton2.Icon = fullfile(pathToMLAPP, 'icons', 'terminate.png');
            app.TerminateButton2.Text = 'Terminate';
            app.ParametersPanel = uipanel(app.Parameters);
            app.ParametersPanel.Title = 'Parameters';
            app.StandardDeviationEditFieldLabel = uilabel(app.ParametersPanel);
            app.StandardDeviationEditFieldLabel.Text = 'Standard Deviation';
            app.StandardDeviationEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.StandardDeviationEditField.Placeholder = 'Enter Standart Deviation';
            app.ThresholdRatioEditFieldLabel = uilabel(app.ParametersPanel);
            app.ThresholdRatioEditFieldLabel.Text = 'Threshold Ratio';
            app.ThresholdRatioEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.IntervalEditFieldLabel = uilabel(app.ParametersPanel);
            app.IntervalEditFieldLabel.Text = 'Interval';
            app.IntervalEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.EyeSpherePanel = uipanel(app.GridLayout2);
            app.EyeSpherePanel.Title = 'EyeSphere';
            app.Image2 = uiimage(app.GridLayout2);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'logos', 'logo2.png');
            app.PrevPage = uibutton(app.GridLayout2, 'push');
            app.PrevPage.ButtonPushedFcn = createCallbackFcn(app, @PrevPageButtonPushed, true);
            app.PrevPage.Text = 'Previous Page';
            app.InitialPage = uibutton(app.GridLayout2, 'push');
            app.InitialPage.ButtonPushedFcn = createCallbackFcn(app, @InitialPageButtonPushed, true);
            app.InitialPage.Text = 'Cover Page';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Group11

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end